classdef PZAdj < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image_V_4               matlab.ui.control.Image
        Image_V_3               matlab.ui.control.Image
        Image_V_2               matlab.ui.control.Image
        Image_V                 matlab.ui.control.Image
        StepEditField_P         matlab.ui.control.NumericEditField
        StepEditField_2Label_3  matlab.ui.control.Label
        StepEditField_R         matlab.ui.control.NumericEditField
        StepEditField_2Label_2  matlab.ui.control.Label
        StepEditField_H         matlab.ui.control.NumericEditField
        StepEditField_2Label    matlab.ui.control.Label
        StepEditField_V         matlab.ui.control.NumericEditField
        StepEditFieldLabel      matlab.ui.control.Label
        ResetButton             matlab.ui.control.Button
        Button_P_Dec            matlab.ui.control.Button
        Slider_P                matlab.ui.control.Slider
        Button_P_Inc            matlab.ui.control.Button
        Button_R_Dec            matlab.ui.control.Button
        Button_R_Inc            matlab.ui.control.Button
        Slider_R                matlab.ui.control.Slider
        Button_H_Dec            matlab.ui.control.Button
        Slider_H                matlab.ui.control.Slider
        Button_H_Inc            matlab.ui.control.Button
        Button_V_Inc            matlab.ui.control.Button
        Slider_V                matlab.ui.control.Slider
        Button_V_Dec            matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        P % Description
        Z % Description
        AdjButton % Description
        HistPZData % Description
        NewPZData % Description
        ff % Description
        yf % Description
        freq
        actP
        actZ
        selPZ
    end
    
    methods (Access = private)
        
        function results = DispBasicPZ(app)
            axPlotPZ = app.UIAxes;
            [mHist, ~] = size(app.HistPZData);
            rP = []; iP = []; iiP = 1;
            rZ = []; iZ = []; iiZ = 1;
            for ii=1:mHist
                PZ = app.HistPZData{ii,2};
                if strcmpi(PZ, 'Pole')
                    rP(iiP) = app.HistPZData{ii,3};
                    iP(iiP) = app.HistPZData{ii,4};
                    iiP = iiP + 1;
                else
                    rZ(iiZ) = app.HistPZData{ii,3};
                    iZ(iiZ) = app.HistPZData{ii,4};
                    iiZ = iiZ + 1;
                end
            end
            plot(axPlotPZ, iP, rP, 'xb', 'LineWidth', 1, 'HitTest', 'off');
            grid(axPlotPZ, 'on');
            hold(axPlotPZ, 'on');
            plot(axPlotPZ, iZ, rZ, 'ob', 'LineWidth', 1, 'HitTest', 'off');
            plot(axPlotPZ, app.ff, app.yf, '-m', 'LineWidth', 0.1, 'HitTest', 'off');
            % plot new data
            [iP, rP, iZ, rZ] = GetPZ(app);
            plot(axPlotPZ, iP, rP, 'xr', 'LineWidth', 1, 'HitTest', 'off');
            plot(axPlotPZ, iZ, rZ, 'or', 'LineWidth', 1, 'HitTest', 'off');
            rZn = app.NewPZData{app.selPZ,3};
            iZn = app.NewPZData{app.selPZ,4};
            PZn = app.NewPZData{app.selPZ,2};
            if strcmpi(PZn,'Pole')
                plot(axPlotPZ, iZn, rZn, 'xr', 'LineWidth', 3, 'HitTest', 'off');
            else
                plot(axPlotPZ, iZn, rZn, 'or', 'LineWidth', 3, 'HitTest', 'off');
            end
            % H
            x = [0, app.freq(end)];
            plot(axPlotPZ, x, [1, 1].*rZn, '-.r', 'LineWidth', 0.1, 'HitTest', 'off');
            % V
            plot(axPlotPZ, [1, 1].*iZn, x, '-.r', 'LineWidth', 0.1, 'HitTest', 'off');
            % R
            if abs(iZn)>1e-6
                k = rZn/iZn;
                plot(axPlotPZ, x, x.*k, '-.r', 'LineWidth', 0.1, 'HitTest', 'off');
            else
                plot(axPlotPZ, [0, 0], x, '-.r', 'LineWidth', 0.1, 'HitTest', 'off');
            end
            % P
            phi = linspace(0, pi/2, 40);
            r = abs(1i*iZn + rZn);
            plot(axPlotPZ, r*sin(phi), r*cos(phi), '-.r', 'LineWidth', 0.1, 'HitTest', 'off');
            
            hold(axPlotPZ, 'off');
            axis(axPlotPZ, 'square');
            ylabel(axPlotPZ, 'real/Hz');
            xlabel(axPlotPZ, 'imag/Hz');
            xlim(axPlotPZ, [0, app.freq(end)]);
            ylim(axPlotPZ, [0, app.freq(end)]);
            title(axPlotPZ, 'PZ Plot');
            % create PZ
            P0 = rP+1i.*iP;
            Z0 = rZ+1i.*iZ;
            P00 = P0(abs(iP)<1e-6);
            P01 = P0(abs(iP)>=1e-6);
            Pall = [P01, conj(P01), P00];
            Pall = [Pall, -Pall];
            if ~isempty(Z0)
                Z00 = Z0(abs(rZ)<1e-6);
                Z01 = Z0(abs(rZ)>=1e-6);
                Zall = [Z01, conj(Z01), Z00];
                Zall = [Zall, -Zall, Zall, -Zall];
            else
                Zall = [];
            end
            % update data
            global G_Data;
            G_Data.P = Pall;
            G_Data.Z = Zall;
            funPublicCalcuPlot(app.CallingApp, 0);
            results = 0;
        end
        
        function results = UpdateSliderValue(app, Data)
            if abs(Data{app.selPZ, 4})<1e-6
                app.Slider_H.Value  = 0;
            else
                app.Slider_H.Value  = Data{app.selPZ, 4};
            end
            if abs(Data{app.selPZ, 3})<1e-6
                app.Slider_V.Value  = 0;
            else
                app.Slider_V.Value  = Data{app.selPZ, 3};
            end
            valueF = Data{app.selPZ, 4}*1i + Data{app.selPZ, 3};
            app.Slider_R.Value  = abs(valueF);
            angV = angle(valueF)/pi*180;
            if angV < 0
                app.Slider_P.Value  = 0;
            else
                app.Slider_P.Value  = angV;
            end
            results = 0;
        end
        
        function [iP, rP, iZ, rZ] = GetPZ(app)
            [mHist, ~] = size(app.NewPZData);
            rP = []; iP = []; iiP = 1;
            rZ = []; iZ = []; iiZ = 1;
            for ii=1:mHist
                PZ = app.NewPZData{ii,2};
                if strcmpi(PZ, 'Pole') && app.NewPZData{ii,1}
                    rP(iiP) = app.NewPZData{ii,3};
                    iP(iiP) = app.NewPZData{ii,4};
                    iiP = iiP + 1;
                elseif strcmpi(PZ, 'Zero') && app.NewPZData{ii,1}
                    rZ(iiZ) = app.NewPZData{ii,3};
                    iZ(iiZ) = app.NewPZData{ii,4};
                    iiZ = iiZ + 1;
                end
            end
        end
        
        function [Data] = CreatePZ(app)
            mP = length(app.actP);
            mZ = length(app.actZ);
            Data = cell(mP+mZ, 4);
            for ii=1:mP
                Data{ii, 1} = true;
                Data{ii, 2} = 'Pole';% P:1,Z:0
                Data{ii, 3} = real(app.actP(ii));
                Data{ii, 4} = imag(app.actP(ii));
            end
            
            for ii=(mP+1):(mP+mZ)
                Data{ii, 1} = true;
                Data{ii, 2} = 'Zero'; % P:1,Z:0
                Data{ii, 3} = real(app.actZ(ii-mP));
                Data{ii, 4} = imag(app.actZ(ii-mP));
            end
        end
        
        function [r, p] = UpdateRPSlider(app)
            h = app.Slider_H.Value;
            v = app.Slider_V.Value;
            z = h + 1i*v;
            r = abs(z);
            p = angle(z)*180/pi;
            app.Slider_R.Value = r;
            app.Slider_P.Value = p;
        end
        
        function [h, v] = UpdateHVSlider(app)
            r = app.Slider_R.Value;
            p = app.Slider_P.Value;
            v = r*sin(p*pi/180);
            h = r*cos(p*pi/180);
            app.Slider_V.Value = v;
            app.Slider_H.Value = h;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, freq, P, Z, ff, yf, AdjButton)
            app.P = P;
            app.Z = Z;
            app.AdjButton = AdjButton;
            app.CallingApp = mainApp;
            app.ff   = ff;
            app.yf   = yf;
            app.freq = freq;
            app.UIFigure.Name  = 'PZAdj';
            Err      = -1e-6;
            app.actP = P(real(P)>=Err & imag(P)>=Err);
            nZ = length(Z);
            if ~mod(nZ,2)
                dZ = Z(1:nZ/2)-Z(nZ/2+1:end);
                if sum(abs(dZ)) < 1e-6
                    Z = Z(1:nZ/2);
                end
            end
            app.actZ = Z(real(Z)>=Err & imag(Z)>=Err);
            % CreatePZ
            [Data] = CreatePZ(app);
            app.HistPZData   = Data;
            app.NewPZData    = Data;
            app.UITable.Data = Data;
            app.selPZ = 1;
            app.Slider_H.Limits = [0, app.freq(end)];
            app.Slider_V.Limits = [0, app.freq(end)];
            app.Slider_R.Limits = [0, app.freq(end)];
            app.Slider_P.Limits = [0, 90];
            UpdateSliderValue(app, Data);
            UpdateRPSlider(app);
            DispBasicPZ(app);
        end

        % Button pushed function: Button_V_Dec
        function Button_V_DecPushed(app, event)
            newValue = app.Slider_V.Value - app.StepEditField_V.Value;
            if newValue<app.Slider_V.Limits(1)
                app.Slider_V.Value = app.Slider_V.Limits(1);
            else
                app.Slider_V.Value = newValue;
            end
            app.UITable.Data{app.selPZ,3} = app.Slider_V.Value;
            app.NewPZData{app.selPZ,3} = app.Slider_V.Value;
            UpdateRPSlider(app);
            DispBasicPZ(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.AdjButton.Enable = 'on';
            delete(app)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.selPZ = indices(1);
            rZ = app.NewPZData{app.selPZ,3};
            iZ = app.NewPZData{app.selPZ,4};
            PZ = app.NewPZData{app.selPZ,2};
            DispBasicPZ(app);
            hold(app.UIAxes, 'on');
            if strcmpi(PZ,'Pole')
                plot(app.UIAxes, iZ, rZ, 'xr', 'LineWidth', 2);
            else
                plot(app.UIAxes, iZ, rZ, 'or', 'LineWidth', 2);
            end
            hold(app.UIAxes, 'off');
            UpdateSliderValue(app, app.NewPZData);
            UpdateRPSlider(app);
        end

        % Button pushed function: Button_V_Inc
        function Button_V_IncPushed(app, event)
            newValue = app.Slider_V.Value + app.StepEditField_V.Value;
            if newValue>app.Slider_V.Limits(2)
                app.Slider_V.Value = app.Slider_V.Limits(2);
            else
                app.Slider_V.Value = newValue;
            end
            app.UITable.Data{app.selPZ,3} = app.Slider_V.Value;
            app.NewPZData{app.selPZ,3} = app.Slider_V.Value;
            UpdateRPSlider(app);
            DispBasicPZ(app);
        end

        % Value changing function: Slider_V
        function Slider_VValueChanging(app, event)
            changingValue = event.Value;
            orgValue = app.UITable.Data{app.selPZ,3};
            newValue = round((changingValue - orgValue)/app.StepEditField_V.Value)*app.StepEditField_V.Value + orgValue;
            app.Slider_V.Value = newValue;
            app.UITable.Data{app.selPZ,3} = app.Slider_V.Value;
            app.NewPZData{app.selPZ,3}    = app.Slider_V.Value;
            UpdateRPSlider(app);
            DispBasicPZ(app);
        end

        % Button pushed function: Button_H_Dec
        function Button_H_DecPushed(app, event)
            newValue = app.Slider_H.Value - app.StepEditField_H.Value;
            if newValue<app.Slider_H.Limits(1)
                app.Slider_H.Value = app.Slider_H.Limits(1);
            else
                app.Slider_H.Value = newValue;
            end
            app.UITable.Data{app.selPZ,4} = app.Slider_H.Value;
            app.NewPZData{app.selPZ,4} = app.Slider_H.Value;
            UpdateRPSlider(app);
            DispBasicPZ(app);
        end

        % Button pushed function: Button_H_Inc
        function Button_H_IncPushed(app, event)
            newValue = app.Slider_H.Value + app.StepEditField_H.Value;
            if newValue>app.Slider_H.Limits(2)
                app.Slider_H.Value = app.Slider_H.Limits(2);
            else
                app.Slider_H.Value = newValue;
            end
            app.UITable.Data{app.selPZ,4} = app.Slider_H.Value;
            app.NewPZData{app.selPZ,4} = app.Slider_H.Value;
            UpdateRPSlider(app);
            DispBasicPZ(app);
        end

        % Value changing function: Slider_H
        function Slider_HValueChanging(app, event)
            changingValue = event.Value;
            orgValue = app.UITable.Data{app.selPZ,4};
            newValue = round((changingValue - orgValue)/app.StepEditField_H.Value)*app.StepEditField_H.Value + orgValue;
            app.Slider_H.Value = newValue;
            app.UITable.Data{app.selPZ,4} = app.Slider_H.Value;
            app.NewPZData{app.selPZ,4}    = app.Slider_H.Value;
            UpdateRPSlider(app);
            DispBasicPZ(app);
        end

        % Button pushed function: Button_R_Dec
        function Button_R_DecPushed(app, event)
            newValue = app.Slider_R.Value - app.StepEditField_R.Value;
            if newValue < app.Slider_R.Limits(1)
                app.Slider_R.Value = app.Slider_R.Limits(1);
            else
                app.Slider_R.Value = newValue;
            end
            [h, v] = UpdateHVSlider(app);
            if h<app.Slider_H.Limits(1)
                app.Slider_H.Value = app.Slider_H.Limits(1);
            else
                app.Slider_H.Value = h;
            end
            if v<app.Slider_V.Limits(1)
                app.Slider_V.Value = app.Slider_V.Limits(1);
            else
                app.Slider_V.Value = v;
            end
            app.UITable.Data{app.selPZ,4} = app.Slider_H.Value;
            app.NewPZData{app.selPZ,4}    = app.Slider_H.Value;
            app.UITable.Data{app.selPZ,3} = app.Slider_V.Value;
            app.NewPZData{app.selPZ,3}    = app.Slider_V.Value;
            DispBasicPZ(app);
        end

        % Button pushed function: Button_R_Inc
        function Button_R_IncPushed(app, event)
            newValue = app.Slider_R.Value + app.StepEditField_R.Value;
            if newValue > app.Slider_R.Limits(2)
                app.Slider_R.Value = app.Slider_R.Limits(2);
            else
                app.Slider_R.Value = newValue;
            end
            [h, v] = UpdateHVSlider(app);
            if h>app.Slider_H.Limits(2)
                app.Slider_H.Value = app.Slider_H.Limits(2);
            else
                app.Slider_H.Value = h;
            end
            if v>app.Slider_V.Limits(2)
                app.Slider_V.Value = app.Slider_V.Limits(2);
            else
                app.Slider_V.Value = v;
            end
            app.UITable.Data{app.selPZ,4} = app.Slider_H.Value;
            app.NewPZData{app.selPZ,4}    = app.Slider_H.Value;
            app.UITable.Data{app.selPZ,3} = app.Slider_V.Value;
            app.NewPZData{app.selPZ,3}    = app.Slider_V.Value;
            DispBasicPZ(app);            
        end

        % Value changing function: Slider_R
        function Slider_RValueChanging(app, event)
            newValue = event.Value;
            if newValue > app.Slider_R.Limits(2)
                app.Slider_R.Value = app.Slider_R.Limits(2);
            elseif newValue < app.Slider_R.Limits(1)
                app.Slider_R.Value = app.Slider_R.Limits(1);
            else
                app.Slider_R.Value = newValue;
            end
            [h, v] = UpdateHVSlider(app);
            if h>app.Slider_H.Limits(2)
                app.Slider_H.Value = app.Slider_H.Limits(2);
            else
                app.Slider_H.Value = h;
            end
            if v>app.Slider_V.Limits(2)
                app.Slider_V.Value = app.Slider_V.Limits(2);
            else
                app.Slider_V.Value = v;
            end
            app.UITable.Data{app.selPZ,4} = app.Slider_H.Value;
            app.NewPZData{app.selPZ,4}    = app.Slider_H.Value;
            app.UITable.Data{app.selPZ,3} = app.Slider_V.Value;
            app.NewPZData{app.selPZ,3}    = app.Slider_V.Value;
            DispBasicPZ(app); 
        end

        % Button pushed function: Button_P_Dec
        function Button_P_DecPushed(app, event)
            newValue = app.Slider_P.Value - app.StepEditField_P.Value;
            if newValue < app.Slider_P.Limits(1)
                app.Slider_P.Value = app.Slider_P.Limits(1);
            else
                app.Slider_P.Value = newValue;
            end
            [h, v] = UpdateHVSlider(app);
            if h<app.Slider_H.Limits(1)
                app.Slider_H.Value = app.Slider_H.Limits(1);
            else
                app.Slider_H.Value = h;
            end
            if v<app.Slider_V.Limits(1)
                app.Slider_V.Value = app.Slider_V.Limits(1);
            else
                app.Slider_V.Value = v;
            end
            app.UITable.Data{app.selPZ,4} = app.Slider_H.Value;
            app.NewPZData{app.selPZ,4}    = app.Slider_H.Value;
            app.UITable.Data{app.selPZ,3} = app.Slider_V.Value;
            app.NewPZData{app.selPZ,3}    = app.Slider_V.Value;
            DispBasicPZ(app);
        end

        % Button pushed function: Button_P_Inc
        function Button_P_IncPushed(app, event)
            newValue = app.Slider_P.Value + app.StepEditField_P.Value;
            if newValue > app.Slider_P.Limits(2)
                app.Slider_P.Value = app.Slider_P.Limits(2);
            else
                app.Slider_P.Value = newValue;
            end
            [h, v] = UpdateHVSlider(app);
            if h>app.Slider_H.Limits(2)
                app.Slider_H.Value = app.Slider_H.Limits(2);
            else
                app.Slider_H.Value = h;
            end
            if v>app.Slider_V.Limits(2)
                app.Slider_V.Value = app.Slider_V.Limits(2);
            else
                app.Slider_V.Value = v;
            end
            app.UITable.Data{app.selPZ,4} = app.Slider_H.Value;
            app.NewPZData{app.selPZ,4}    = app.Slider_H.Value;
            app.UITable.Data{app.selPZ,3} = app.Slider_V.Value;
            app.NewPZData{app.selPZ,3}    = app.Slider_V.Value;
            DispBasicPZ(app);    
        end

        % Value changing function: Slider_P
        function Slider_PValueChanging(app, event)
            newValue = event.Value;
            if newValue > app.Slider_P.Limits(2)
                app.Slider_P.Value = app.Slider_P.Limits(2);
            elseif newValue < app.Slider_P.Limits(1)
                app.Slider_P.Value = app.Slider_P.Limits(1);
            else
                app.Slider_P.Value = newValue;
            end
            [h, v] = UpdateHVSlider(app);
            if h>app.Slider_H.Limits(2)
                app.Slider_H.Value = app.Slider_H.Limits(2);
            else
                app.Slider_H.Value = h;
            end
            if v>app.Slider_V.Limits(2)
                app.Slider_V.Value = app.Slider_V.Limits(2);
            else
                app.Slider_V.Value = v;
            end
            app.UITable.Data{app.selPZ,4} = app.Slider_H.Value;
            app.NewPZData{app.selPZ,4}    = app.Slider_H.Value;
            app.UITable.Data{app.selPZ,3} = app.Slider_V.Value;
            app.NewPZData{app.selPZ,3}    = app.Slider_V.Value;
            DispBasicPZ(app); 
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            data = event.IntersectionPoint;
            h = data(1);
            v = data(2);
            app.Slider_H.Value = h;
            app.UITable.Data{app.selPZ,4} = app.Slider_H.Value;
            app.NewPZData{app.selPZ,4}    = app.Slider_H.Value;
            app.Slider_V.Value = v;
            app.UITable.Data{app.selPZ,3} = app.Slider_V.Value;
            app.NewPZData{app.selPZ,3}    = app.Slider_V.Value;
%             UpdateHVSlider(app);
            UpdateRPSlider(app);
            DispBasicPZ(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            Data             = app.HistPZData;
            app.NewPZData    = Data;
            app.UITable.Data = Data;
            app.Slider_V.Value = app.UITable.Data{app.selPZ,3};
            app.Slider_H.Value = app.UITable.Data{app.selPZ,4};
%             UpdateHVSlider(app);
            UpdateRPSlider(app);
            DispBasicPZ(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            [a, b] = size(app.UITable.Data);
            EnablePZ = zeros(1, a);
            for ii=1:a
                EnablePZ(ii) = app.UITable.Data{ii, 1};
            end
            if sum(EnablePZ)
                app.UITable.Data{indices(1), indices(2)} = newData;
                app.NewPZData{indices(1), indices(2)}    = newData;
%                 UpdateHVSlider(app);
                UpdateRPSlider(app);
                DispBasicPZ(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Enable'; 'PZ'; 'Real'; 'image'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.Button_V_Dec = uibutton(app.UIFigure, 'push');
            app.Button_V_Dec.ButtonPushedFcn = createCallbackFcn(app, @Button_V_DecPushed, true);
            app.Button_V_Dec.Text = '<';
            app.Slider_V = uislider(app.UIFigure);
            app.Slider_V.ValueChangingFcn = createCallbackFcn(app, @Slider_VValueChanging, true);
            app.Button_V_Inc = uibutton(app.UIFigure, 'push');
            app.Button_V_Inc.ButtonPushedFcn = createCallbackFcn(app, @Button_V_IncPushed, true);
            app.Button_V_Inc.Text = '>';
            app.Button_H_Inc = uibutton(app.UIFigure, 'push');
            app.Button_H_Inc.ButtonPushedFcn = createCallbackFcn(app, @Button_H_IncPushed, true);
            app.Button_H_Inc.Text = '>';
            app.Slider_H = uislider(app.UIFigure);
            app.Slider_H.ValueChangingFcn = createCallbackFcn(app, @Slider_HValueChanging, true);
            app.Button_H_Dec = uibutton(app.UIFigure, 'push');
            app.Button_H_Dec.ButtonPushedFcn = createCallbackFcn(app, @Button_H_DecPushed, true);
            app.Button_H_Dec.Text = '<';
            app.Slider_R = uislider(app.UIFigure);
            app.Slider_R.ValueChangingFcn = createCallbackFcn(app, @Slider_RValueChanging, true);
            app.Button_R_Inc = uibutton(app.UIFigure, 'push');
            app.Button_R_Inc.ButtonPushedFcn = createCallbackFcn(app, @Button_R_IncPushed, true);
            app.Button_R_Inc.Text = '>';
            app.Button_R_Dec = uibutton(app.UIFigure, 'push');
            app.Button_R_Dec.ButtonPushedFcn = createCallbackFcn(app, @Button_R_DecPushed, true);
            app.Button_R_Dec.Text = '<';
            app.Button_P_Inc = uibutton(app.UIFigure, 'push');
            app.Button_P_Inc.ButtonPushedFcn = createCallbackFcn(app, @Button_P_IncPushed, true);
            app.Button_P_Inc.Text = '>';
            app.Slider_P = uislider(app.UIFigure);
            app.Slider_P.ValueChangingFcn = createCallbackFcn(app, @Slider_PValueChanging, true);
            app.Button_P_Dec = uibutton(app.UIFigure, 'push');
            app.Button_P_Dec.ButtonPushedFcn = createCallbackFcn(app, @Button_P_DecPushed, true);
            app.Button_P_Dec.Text = '<';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StepEditFieldLabel = uilabel(app.UIFigure);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField_V = uieditfield(app.UIFigure, 'numeric');
            app.StepEditField_V.Value = 0.01;
            app.StepEditField_2Label = uilabel(app.UIFigure);
            app.StepEditField_2Label.Text = 'Step';
            app.StepEditField_H = uieditfield(app.UIFigure, 'numeric');
            app.StepEditField_H.Value = 0.01;
            app.StepEditField_2Label_2 = uilabel(app.UIFigure);
            app.StepEditField_2Label_2.Text = 'Step';
            app.StepEditField_R = uieditfield(app.UIFigure, 'numeric');
            app.StepEditField_R.Limits = [0 Inf];
            app.StepEditField_R.Value = 0.01;
            app.StepEditField_2Label_3 = uilabel(app.UIFigure);
            app.StepEditField_2Label_3.Text = 'Step';
            app.StepEditField_P = uieditfield(app.UIFigure, 'numeric');
            app.StepEditField_P.Limits = [0 360];
            app.StepEditField_P.Value = 1;
            app.Image_V = uiimage(app.UIFigure);
            app.Image_V.ImageSource = 'image_V.png';
            app.Image_V_2 = uiimage(app.UIFigure);
            app.Image_V_2.ImageSource = 'image_H.png';
            app.Image_V_3 = uiimage(app.UIFigure);
            app.Image_V_3.ImageSource = 'image_R.png';
            app.Image_V_4 = uiimage(app.UIFigure);
            app.Image_V_4.ImageSource = 'image_P.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PZAdj(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end