classdef Trim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        strUnitLabel           matlab.ui.control.Label
        StepEditField          matlab.ui.control.NumericEditField
        StepEditFieldLabel     matlab.ui.control.Label
        MaxEditField           matlab.ui.control.NumericEditField
        MaxEditFieldLabel      matlab.ui.control.Label
        MinEditField           matlab.ui.control.NumericEditField
        MinEditFieldLabel      matlab.ui.control.Label
        CurrentEditField       matlab.ui.control.NumericEditField
        CurrentEditFieldLabel  matlab.ui.control.Label
        Button_inc             matlab.ui.control.Button
        Button_dec             matlab.ui.control.Button
        Slider                 matlab.ui.control.Slider
    end

    
    properties (Access = private)
        CallingApp % CallingApp
        TrimButton % Description
        strUnit % Description
        TrimEdit % Description
        Label
    end
    
    methods (Access = private)
        
        function results = UpdataData(app)
            results = [];
            if app.MinEditField.Value < app.MaxEditField.Value
                if app.CurrentEditField.Value < app.MinEditField.Value
                    app.CurrentEditField.Value = app.MinEditField.Value;
                end
                if app.CurrentEditField.Value > app.MaxEditField.Value
                    app.CurrentEditField.Value = app.MaxEditField.Value;
                end
                app.Slider.Limits = [app.MinEditField.Value, app.MaxEditField.Value];
                app.Slider.Value = app.CurrentEditField.Value;
                if app.StepEditField.Value > 0
                    N = (app.MaxEditField.Value - app.MinEditField.Value)/app.StepEditField.Value;
                    if N>10
                        N = 10;
                    elseif N<1
                        N = 1;
                    end
                else
                    N = 10;
                end
                app.Slider.MajorTicks = linspace(app.MinEditField.Value, app.MaxEditField.Value, N+1);
                results = funUpdatePublicCalcuPlot(app);
%                 app.Slider.MajorTickLabels = linspace(app.MinEditField.Value, app.MaxEditField.Value, N);
            end
        end
        
        function results = funUpdatePublicCalcuPlot(app)
            global G_Data;
            G_Data.(app.Label) = app.CurrentEditField.Value;
            app.TrimEdit.Value = num2str(app.CurrentEditField.Value);
            results = funPublicCalcuPlot(app.CallingApp, 1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, CurrentValue, MinValue, MaxValue, StepValue, Label, TrimButton, strUnit, TrimEdit)
            app.CurrentEditField.Value = str2double(CurrentValue);
            app.MinEditField.Value     = MinValue;
            app.MaxEditField.Value     = MaxValue;
            app.StepEditField.Value    = StepValue;
            app.CallingApp             = MainApp;
            app.UIFigure.Name          = sprintf('%s Trim', Label);
            app.Label                  = Label;
            app.TrimButton             = TrimButton;
            app.strUnit                = strUnit;
            app.TrimEdit               = TrimEdit;
            set(app.strUnitLabel, 'Text', app.strUnit);
            UpdataData(app);
        end

        % Button pushed function: Button_dec
        function Button_decPushed(app, event)
            app.CurrentEditField.Value = app.CurrentEditField.Value - app.StepEditField.Value;
            if app.CurrentEditField.Value < app.MinEditField.Value
                app.CurrentEditField.Value = app.MinEditField.Value;
            end
            app.Slider.Value = app.CurrentEditField.Value;
            results = funUpdatePublicCalcuPlot(app);
        end

        % Button pushed function: Button_inc
        function Button_incPushed(app, event)
            app.CurrentEditField.Value = app.CurrentEditField.Value + app.StepEditField.Value;
            if app.CurrentEditField.Value > app.MaxEditField.Value
                app.CurrentEditField.Value = app.MaxEditField.Value;
            end
            app.Slider.Value = app.CurrentEditField.Value;
            results = funUpdatePublicCalcuPlot(app);
        end

        % Value changed function: MinEditField
        function MinEditFieldValueChanged(app, event)
            value = app.MinEditField.Value;
            UpdataData(app);
        end

        % Value changed function: CurrentEditField
        function CurrentEditFieldValueChanged(app, event)
            value = app.CurrentEditField.Value;
            UpdataData(app);
        end

        % Value changed function: MaxEditField
        function MaxEditFieldValueChanged(app, event)
            value = app.MaxEditField.Value;
            UpdataData(app);
        end

        % Value changed function: StepEditField
        function StepEditFieldValueChanged(app, event)
            value = app.StepEditField.Value;
            UpdataData(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.TrimButton.Enable = 'on';
            delete(app)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
%             N = floor((app.MaxEditField.Value-app.MinEditField.Value)/app.StepEditField.Value);
            n = round((changingValue-app.MinEditField.Value)/app.StepEditField.Value);
            newValue = app.StepEditField.Value*n + app.MinEditField.Value;
            if app.CurrentEditField.Value ~= newValue
                app.CurrentEditField.Value = newValue;
                results = funUpdatePublicCalcuPlot(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Button_dec = uibutton(app.UIFigure, 'push');
            app.Button_dec.ButtonPushedFcn = createCallbackFcn(app, @Button_decPushed, true);
            app.Button_dec.Text = '<';
            app.Button_inc = uibutton(app.UIFigure, 'push');
            app.Button_inc.ButtonPushedFcn = createCallbackFcn(app, @Button_incPushed, true);
            app.Button_inc.Text = '>';
            app.CurrentEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.CurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentEditFieldValueChanged, true);
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField.ValueChangedFcn = createCallbackFcn(app, @MinEditFieldValueChanged, true);
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.ValueChangedFcn = createCallbackFcn(app, @MaxEditFieldValueChanged, true);
            app.MaxEditField.Value = 1;
            app.StepEditFieldLabel = uilabel(app.UIFigure);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepEditField.Limits = [0 Inf];
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.StepEditField.Value = 0.1;
            app.strUnitLabel = uilabel(app.UIFigure);
            app.strUnitLabel.Text = 'strUnit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trim(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end