classdef ZeroPoleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PZAdjButton              matlab.ui.control.Button
        TrimAsButton             matlab.ui.control.Button
        TrimApButton             matlab.ui.control.Button
        TrimFpButton             matlab.ui.control.Button
        dBLabel_As               matlab.ui.control.Label
        dBLabel_Ap               matlab.ui.control.Label
        HzLabel                  matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        FpEditField              matlab.ui.control.EditField
        FpEditFieldLabel         matlab.ui.control.Label
        ResponseDropDown         matlab.ui.control.DropDown
        ResponseDropDownLabel    matlab.ui.control.Label
        AsEditField              matlab.ui.control.EditField
        AsEditFieldLabel         matlab.ui.control.Label
        ApEditField              matlab.ui.control.EditField
        ApEditFieldLabel         matlab.ui.control.Label
        OrderDropDown            matlab.ui.control.DropDown
        OrderDropDownLabel       matlab.ui.control.Label
        FilterTypeDropDown       matlab.ui.control.DropDown
        FilterTypeDropDownLabel  matlab.ui.control.Label
        UIAxes_FR                matlab.ui.control.UIAxes
        UIAxes_PZ                matlab.ui.control.UIAxes
        UIAxes_3D                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TrimFpApp % TrimApp
        TrimApApp
        TrimAsApp
        PZAdjApp % Description
    end
    
    
    methods (Access = public)
        
        function results = funPublicCalcuPlot(app, IdealPZEn)
            global G_Data;
            G_Data.InitEn = 0;
            % *******************************Calcu*************************
            [iP, rP, iZ, rZ, ff, yf, HsFR, MagFp, Hs, Hs0, P, Z] = funCalcuParameter(G_Data, IdealPZEn);
            G_Data.P = P;G_Data.Z = Z;G_Data.ff = ff;G_Data.yf = yf;
            % ********************PLOT*************************************
            InitEn = funPlotParameter(app, iP, rP, iZ, rZ, ff, yf, HsFR, MagFp, Hs, Hs0, G_Data.InitEn);
            G_Data.InitEn = InitEn;
            results = InitEn;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name  = 'ZeroPoleAnalyzer';
            PlotButtonPushed(app, []);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % ******************************INIT***************************
            global G_Data;
            [G_Data] = funInitParameter(app);
            G_Data.InitEn = 1;
            % *******************************Calcu*************************
            [iP, rP, iZ, rZ, ff, yf, HsFR, MagFp, Hs, Hs0, P, Z] = funCalcuParameter(G_Data, 1);
            G_Data.P = P;G_Data.Z = Z;G_Data.ff = ff;G_Data.yf = yf;
            % ********************PLOT*************************************
            InitEn = funPlotParameter(app, iP, rP, iZ, rZ, ff, yf, HsFR, MagFp, Hs, Hs0, G_Data.InitEn);
            G_Data.InitEn = InitEn;
        end

        % Button pushed function: TrimFpButton
        function TrimFpButtonPushed(app, event)
            app.TrimFpButton.Enable = 'off';
            app.TrimFpApp = Trim(app, app.FpEditField.Value, 0.1, 2, 0.1, 'fp', app.TrimFpButton, 'Hz', app.FpEditField);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.TrimFpApp);
            delete(app.TrimApApp);
            delete(app.TrimAsApp);
            delete(app.PZAdjApp);
            delete(app)
        end

        % Button pushed function: TrimApButton
        function TrimApButtonPushed(app, event)
            app.TrimApButton.Enable = 'off';
            app.TrimApApp = Trim(app, app.ApEditField.Value, 0.1, 3, 0.1, 'Ap', app.TrimApButton, 'dB', app.ApEditField);
        end

        % Button pushed function: TrimAsButton
        function TrimAsButtonPushed(app, event)
            app.TrimAsButton.Enable = 'off';
            app.TrimAsApp = Trim(app, app.AsEditField.Value, 10, 100, 1, 'As', app.TrimAsButton, 'dB', app.AsEditField);
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            value = app.FilterTypeDropDown.Value;
            PlotButtonPushed(app, event);
        end

        % Value changed function: OrderDropDown
        function OrderDropDownValueChanged(app, event)
            value = app.OrderDropDown.Value;
            PlotButtonPushed(app, event);
        end

        % Button pushed function: PZAdjButton
        function PZAdjButtonPushed(app, event)
            app.PZAdjButton.Enable = 'off';
            global G_Data;
            P = G_Data.P;
            Z = G_Data.Z;
            ff = G_Data.ff;yf = G_Data.yf;
            freq = G_Data.freq;
            AdjButton = app.PZAdjButton;
            app.PZAdjApp = PZAdj(app, freq, P, Z, ff, yf, AdjButton);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_3D = uiaxes(app.UIFigure);
            title(app.UIAxes_3D, 'Title')
            xlabel(app.UIAxes_3D, 'X')
            ylabel(app.UIAxes_3D, 'Y')
            zlabel(app.UIAxes_3D, 'Z')
            app.UIAxes_PZ = uiaxes(app.UIFigure);
            title(app.UIAxes_PZ, 'Title')
            xlabel(app.UIAxes_PZ, 'X')
            ylabel(app.UIAxes_PZ, 'Y')
            zlabel(app.UIAxes_PZ, 'Z')
            app.UIAxes_FR = uiaxes(app.UIFigure);
            title(app.UIAxes_FR, 'Title')
            xlabel(app.UIAxes_FR, 'X')
            ylabel(app.UIAxes_FR, 'Y')
            zlabel(app.UIAxes_FR, 'Z')
            app.FilterTypeDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypeDropDownLabel.Text = 'FilterType';
            app.FilterTypeDropDown = uidropdown(app.UIFigure);
            app.FilterTypeDropDown.Items = {'Butterworth', 'Chebyshev I', 'Chebyshev II', 'Elliptic'};
            app.FilterTypeDropDown.Editable = 'on';
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'Butterworth';
            app.OrderDropDownLabel = uilabel(app.UIFigure);
            app.OrderDropDownLabel.Text = 'Order';
            app.OrderDropDown = uidropdown(app.UIFigure);
            app.OrderDropDown.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21'};
            app.OrderDropDown.Editable = 'on';
            app.OrderDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderDropDownValueChanged, true);
            app.OrderDropDown.Value = '5';
            app.ApEditFieldLabel = uilabel(app.UIFigure);
            app.ApEditFieldLabel.Text = 'Ap';
            app.ApEditField = uieditfield(app.UIFigure, 'text');
            app.ApEditField.Value = '3.01';
            app.AsEditFieldLabel = uilabel(app.UIFigure);
            app.AsEditFieldLabel.Text = 'As';
            app.AsEditField = uieditfield(app.UIFigure, 'text');
            app.AsEditField.Value = '40';
            app.ResponseDropDownLabel = uilabel(app.UIFigure);
            app.ResponseDropDownLabel.Text = 'Response';
            app.ResponseDropDown = uidropdown(app.UIFigure);
            app.ResponseDropDown.Items = {'LPF'};
            app.ResponseDropDown.Value = 'LPF';
            app.FpEditFieldLabel = uilabel(app.UIFigure);
            app.FpEditFieldLabel.Text = 'Fp';
            app.FpEditField = uieditfield(app.UIFigure, 'text');
            app.FpEditField.Value = '1';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.dBLabel_Ap = uilabel(app.UIFigure);
            app.dBLabel_Ap.Text = 'dB';
            app.dBLabel_As = uilabel(app.UIFigure);
            app.dBLabel_As.Text = 'dB';
            app.TrimFpButton = uibutton(app.UIFigure, 'push');
            app.TrimFpButton.ButtonPushedFcn = createCallbackFcn(app, @TrimFpButtonPushed, true);
            app.TrimFpButton.Text = 'TrimFp';
            app.TrimApButton = uibutton(app.UIFigure, 'push');
            app.TrimApButton.ButtonPushedFcn = createCallbackFcn(app, @TrimApButtonPushed, true);
            app.TrimApButton.Text = 'TrimAp';
            app.TrimAsButton = uibutton(app.UIFigure, 'push');
            app.TrimAsButton.ButtonPushedFcn = createCallbackFcn(app, @TrimAsButtonPushed, true);
            app.TrimAsButton.Text = 'TrimAs';
            app.PZAdjButton = uibutton(app.UIFigure, 'push');
            app.PZAdjButton.ButtonPushedFcn = createCallbackFcn(app, @PZAdjButtonPushed, true);
            app.PZAdjButton.Text = 'PZAdj';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ZeroPoleApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end